classdef app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure      matlab.ui.Figure
        TLabel        matlab.ui.control.Label
        DLabel        matlab.ui.control.Label
        PLabel        matlab.ui.control.Label
        NormalLabel   matlab.ui.control.Label
        ImageT        matlab.ui.control.Image
        ImageD        matlab.ui.control.Image
        ImageP        matlab.ui.control.Image
        ImageN        matlab.ui.control.Image
        browseButton  matlab.ui.control.Button
    end


    methods (Static)
        function results(app, name, ext)
        app.ImageN.ImageSource = strcat(name, ext),'jpeg';
        app.ImageP.ImageSource = strcat(name, '_p', ext),'jpeg';
        app.ImageD.ImageSource = strcat(name, '_d', ext),'jpeg';
        app.ImageT.ImageSource = strcat(name, '_t', ext),'jpeg';
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: browseButton
        function browseButtonPushed(app, event)
        [a,b] = uigetfile();
        a = strcat(b,a);
        imageName = a;   
        close(app.UIFigure); 
        Daltonization.daltonize(imageName, 'wide_gamut_rgb', 'ciecam02');
       
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.browseButton = uibutton(app.UIFigure, 'push');
            app.browseButton.ButtonPushedFcn = createCallbackFcn(app, @browseButtonPushed, true);
            app.browseButton.Text = 'browse';
            app.ImageN = uiimage(app.UIFigure);
            app.ImageP = uiimage(app.UIFigure);
            app.ImageD = uiimage(app.UIFigure);
            app.ImageT = uiimage(app.UIFigure);
            app.NormalLabel = uilabel(app.UIFigure);
            app.NormalLabel.Text = 'Normal';
            app.PLabel = uilabel(app.UIFigure);
            app.PLabel.Text = 'P';
            app.DLabel = uilabel(app.UIFigure);
            app.DLabel.Text = 'D';
            app.TLabel = uilabel(app.UIFigure);
            app.TLabel.Text = 'T';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end